classdef TheUnscrambler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TheUnscramblerUIFigure       matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        CtrlNMenu                    matlab.ui.container.Menu
        Menu_11                      matlab.ui.container.Menu
        CtrlOMenu                    matlab.ui.container.Menu
        Menu_7                       matlab.ui.container.Menu
        UnscramblerResultsCtrlUMenu  matlab.ui.container.Menu
        UnscramblerDataMenu          matlab.ui.container.Menu
        U5ResultsMenu                matlab.ui.container.Menu
        U5DataMenu                   matlab.ui.container.Menu
        UnscramblerASCIIMenu         matlab.ui.container.Menu
        ASCIIMenu                    matlab.ui.container.Menu
        LotusMenu                    matlab.ui.container.Menu
        ExcelMenu                    matlab.ui.container.Menu
        MatlabMenu                   matlab.ui.container.Menu
        NSASMenu                     matlab.ui.container.Menu
        DatabaseMenu                 matlab.ui.container.Menu
        JCAMPDXMenu                  matlab.ui.container.Menu
        TrackerMenu                  matlab.ui.container.Menu
        APCMenu                      matlab.ui.container.Menu
        GRAMSMenu                    matlab.ui.container.Menu
        MVACDFMenu                   matlab.ui.container.Menu
        CLASSPASpectrOnMenu          matlab.ui.container.Menu
        IndicoMenu                   matlab.ui.container.Menu
        BFF3Menu                     matlab.ui.container.Menu
        OPUSMenu                     matlab.ui.container.Menu
        UserDefinedImportUDIMenu     matlab.ui.container.Menu
        DMenu                        matlab.ui.container.Menu
        UnscramblerResultsMenu       matlab.ui.container.Menu
        ASCIIMenu_2                  matlab.ui.container.Menu
        ExcelMenu_2                  matlab.ui.container.Menu
        JCAMPDXMenu_2                matlab.ui.container.Menu
        MatlabMenu_2                 matlab.ui.container.Menu
        F3DMenu                      matlab.ui.container.Menu
        UDIMenu                      matlab.ui.container.Menu
        RegisterNewUDIMenu           matlab.ui.container.Menu
        UnregisterUDIMenu            matlab.ui.container.Menu
        Menu_8                       matlab.ui.container.Menu
        Menu_9                       matlab.ui.container.Menu
        Menu_10                      matlab.ui.container.Menu
        AltF4Menu                    matlab.ui.container.Menu
        Menu_2                       matlab.ui.container.Menu
        Menu_5                       matlab.ui.container.Menu
        Menu_6                       matlab.ui.container.Menu
        Menu_3                       matlab.ui.container.Menu
        CtrlRMenu                    matlab.ui.container.Menu
        PCAMenu                      matlab.ui.container.Menu
        Menu_21                      matlab.ui.container.Menu
        Menu_22                      matlab.ui.container.Menu
        Menu_13                      matlab.ui.container.Menu
        Menu_23                      matlab.ui.container.Menu
        Menu_24                      matlab.ui.container.Menu
        Menu_25                      matlab.ui.container.Menu
        MCRMenu                      matlab.ui.container.Menu
        MSCEMSCMenu                  matlab.ui.container.Menu
        Menu_12                      matlab.ui.container.Menu
        Menu_26                      matlab.ui.container.Menu
        Menu_4                       matlab.ui.container.Menu
        Menu_20                      matlab.ui.container.Menu
        Menu_16                      matlab.ui.container.Menu
        Menu_14                      matlab.ui.container.Menu
        Menu_15                      matlab.ui.container.Menu
        Menu_17                      matlab.ui.container.Menu
        Menu_18                      matlab.ui.container.Menu
        Menu_19                      matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        PushTool2                    matlab.ui.container.toolbar.PushTool
        PushTool3                    matlab.ui.container.toolbar.PushTool
        PushTool4                    matlab.ui.container.toolbar.PushTool
        Image                        matlab.ui.control.Image
        HTML                         matlab.ui.control.HTML
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            if app.Toolbar.Visible == true
               app.Menu_5.Text = '        工具条       ';
               app.Toolbar.Visible = false;
            elseif app.Toolbar.Visible == false
               app.Menu_5.Text = '✔     工具条       ';
               app.Toolbar.Visible = true;
            end
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            app.Image.ImageSource = 'the2.png';
        end

        % Menu selected function: CtrlNMenu
        function CtrlNMenuSelected(app, event)
            app.HTML.Visible = true;
            %app.HTML.Data = "visiable";
            %delete(app.HTML);
            
            %app.HTML = uihtml(app.TheUnscramblerUIFigure);
            %app.HTML.HTMLSource = 'C:\Users\艾何欣\Desktop\need\style.html';
            %app.HTML.Position = [540 200 507 340];
            %app.HTML.Data = "visiable";
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
            if data == "true"
                global chooseFile
                chooseFile ='数据3.xlsx';
                delete(app.TheUnscramblerUIFigure);
                run TheUnscramblerDATA.mlapp;
            elseif data == "false"
                app.HTML.Visible = false;
            end
        end

        % Menu selected function: ExcelMenu
        function ExcelMenuSelected(app, event)
            % 获取文件路径和文件名
            [filename, pathname] = uigetfile({'*.xls;*.csv;*.xlsx;*.txt', 'Supported File Formats'}, '选择文件');
            %[filename,pathname] = uigetfile({'*.xls';'*.txt';'*.xlsx';'*.csv';});
            % 如果用户没有选择文件，则返回
            if isequal(filename, 0)    
                disp('用户没有选择文件。'); 
                return;
            end
            % 获取文件的拓展名
            [~, ~, ext] = fileparts(filename);
            % 判断拓展名是否为"xls"、"csv"、"xlsx"、"txt"
            validExtensions = {'.xls', '.csv', '.xlsx', '.txt'};
            if ~any(strcmpi(ext, validExtensions))    
                % 弹出警告框    
                warndlg('请选择拓展名为xls、csv、xlsx或txt的文件。', '警告');    
                return;
            end
            % 拼接文件名和路径
            Files = strcat(pathname,filename);
            global chooseFile
            chooseFile = Files;
            run TheUnscramblerDATA1.mlapp;
            delete(app.TheUnscramblerUIFigure); 
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            app.HTML.Visible = true;
        end

        % Menu selected function: CtrlOMenu
        function CtrlOMenuSelected(app, event)
            % 获取文件路径和文件名
            [filename, pathname] = uigetfile({'*.xls;*.csv;*.xlsx;*.txt', 'Supported File Formats'}, '选择文件');
            %[filename,pathname] = uigetfile({'*.xls';'*.txt';'*.xlsx';'*.csv';});
            % 如果用户没有选择文件，则返回
            if isequal(filename, 0)    
                disp('用户没有选择文件。'); 
                return;
            end
            % 获取文件的拓展名
            [~, ~, ext] = fileparts(filename);
            % 判断拓展名是否为"xls"、"csv"、"xlsx"、"txt"
            validExtensions = {'.xls', '.csv', '.xlsx', '.txt'};
            if ~any(strcmpi(ext, validExtensions))    
                % 弹出警告框    
                warndlg('请选择拓展名为xls、csv、xlsx或txt的文件。', '警告');    
                return;
            end
            % 拼接文件名和路径
            Files = strcat(pathname,filename);
            global chooseFile
            chooseFile = Files;
            run TheUnscramblerDATA1.mlapp;
            delete(app.TheUnscramblerUIFigure); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TheUnscramblerUIFigure = uifigure('Visible', 'off');
            app.TheUnscramblerUIFigure.Name = 'The Unscrambler';
            app.Menu = uimenu(app.TheUnscramblerUIFigure);
            app.Menu.Text = '文件';
            app.CtrlNMenu = uimenu(app.Menu);
            app.CtrlNMenu.MenuSelectedFcn = createCallbackFcn(app, @CtrlNMenuSelected, true);
            app.CtrlNMenu.Text = '新建...                         Ctrl+N';
            app.Menu_11 = uimenu(app.Menu);
            app.Menu_11.Text = '新建设计...';
            app.CtrlOMenu = uimenu(app.Menu);
            app.CtrlOMenu.MenuSelectedFcn = createCallbackFcn(app, @CtrlOMenuSelected, true);
            app.CtrlOMenu.Text = '打开                          Ctrl+O';
            app.Menu_7 = uimenu(app.Menu);
            app.Menu_7.Text = '导入                ';
            app.UnscramblerResultsCtrlUMenu = uimenu(app.Menu_7);
            app.UnscramblerResultsCtrlUMenu.Text = 'Unscrambler Results...                    Ctrl+U';
            app.UnscramblerDataMenu = uimenu(app.Menu_7);
            app.UnscramblerDataMenu.Text = 'Unscrambler Data...';
            app.U5ResultsMenu = uimenu(app.Menu_7);
            app.U5ResultsMenu.Text = 'U5 Results...';
            app.U5DataMenu = uimenu(app.Menu_7);
            app.U5DataMenu.Text = 'U5 Data...';
            app.UnscramblerASCIIMenu = uimenu(app.Menu_7);
            app.UnscramblerASCIIMenu.Text = 'Unscrambler ASCII...';
            app.ASCIIMenu = uimenu(app.Menu_7);
            app.ASCIIMenu.Text = 'ASCII...';
            app.LotusMenu = uimenu(app.Menu_7);
            app.LotusMenu.Text = 'Lotus...';
            app.ExcelMenu = uimenu(app.Menu_7);
            app.ExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcelMenuSelected, true);
            app.ExcelMenu.Text = 'Excel...';
            app.MatlabMenu = uimenu(app.Menu_7);
            app.MatlabMenu.Text = 'Matlab...';
            app.NSASMenu = uimenu(app.Menu_7);
            app.NSASMenu.Text = 'NSAS...';
            app.DatabaseMenu = uimenu(app.Menu_7);
            app.DatabaseMenu.Text = 'Database...';
            app.JCAMPDXMenu = uimenu(app.Menu_7);
            app.JCAMPDXMenu.Text = 'JCAMP-DX...';
            app.TrackerMenu = uimenu(app.Menu_7);
            app.TrackerMenu.Text = 'Tracker...';
            app.APCMenu = uimenu(app.Menu_7);
            app.APCMenu.Text = 'APC...';
            app.GRAMSMenu = uimenu(app.Menu_7);
            app.GRAMSMenu.Text = 'GRAMS...';
            app.MVACDFMenu = uimenu(app.Menu_7);
            app.MVACDFMenu.Text = 'MVACDF...';
            app.CLASSPASpectrOnMenu = uimenu(app.Menu_7);
            app.CLASSPASpectrOnMenu.Text = 'CLASS-PA & SpectrOn...';
            app.IndicoMenu = uimenu(app.Menu_7);
            app.IndicoMenu.Text = 'Indico...';
            app.BFF3Menu = uimenu(app.Menu_7);
            app.BFF3Menu.Text = 'BFF3...';
            app.OPUSMenu = uimenu(app.Menu_7);
            app.OPUSMenu.Text = 'OPUS...';
            app.UserDefinedImportUDIMenu = uimenu(app.Menu_7);
            app.UserDefinedImportUDIMenu.Text = 'User Defined Import(UDI)...';
            app.DMenu = uimenu(app.Menu);
            app.DMenu.Text = '导入 3-D';
            app.UnscramblerResultsMenu = uimenu(app.DMenu);
            app.UnscramblerResultsMenu.Text = 'Unscrambler Results...';
            app.ASCIIMenu_2 = uimenu(app.DMenu);
            app.ASCIIMenu_2.Text = 'ASCII...';
            app.ExcelMenu_2 = uimenu(app.DMenu);
            app.ExcelMenu_2.Text = 'Excel...';
            app.JCAMPDXMenu_2 = uimenu(app.DMenu);
            app.JCAMPDXMenu_2.Text = 'JCAMP-DX...';
            app.MatlabMenu_2 = uimenu(app.DMenu);
            app.MatlabMenu_2.Text = 'Matlab...';
            app.F3DMenu = uimenu(app.DMenu);
            app.F3DMenu.Text = 'F3D...';
            app.UDIMenu = uimenu(app.Menu);
            app.UDIMenu.Text = 'UDI';
            app.RegisterNewUDIMenu = uimenu(app.UDIMenu);
            app.RegisterNewUDIMenu.Text = 'Register New UDI';
            app.UnregisterUDIMenu = uimenu(app.UDIMenu);
            app.UnregisterUDIMenu.Text = 'Unregister UDI';
            app.Menu_8 = uimenu(app.Menu);
            app.Menu_8.Text = '打印设置...';
            app.Menu_9 = uimenu(app.Menu);
            app.Menu_9.Text = '系统设置...';
            app.Menu_10 = uimenu(app.Menu);
            app.Menu_10.Text = '最近的文件';
            app.AltF4Menu = uimenu(app.Menu);
            app.AltF4Menu.Text = '退出                              Alt+F4';
            app.Menu_2 = uimenu(app.TheUnscramblerUIFigure);
            app.Menu_2.Text = '视图';
            app.Menu_5 = uimenu(app.Menu_2);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '✔     工具条       ';
            app.Menu_6 = uimenu(app.Menu_2);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '✔     状态条       ';
            app.Menu_3 = uimenu(app.TheUnscramblerUIFigure);
            app.Menu_3.Text = '结果';
            app.CtrlRMenu = uimenu(app.Menu_3);
            app.CtrlRMenu.Text = '总体...                                Ctrl+R';
            app.PCAMenu = uimenu(app.Menu_3);
            app.PCAMenu.Text = 'PCA...';
            app.Menu_21 = uimenu(app.Menu_3);
            app.Menu_21.Text = '回退...';
            app.Menu_22 = uimenu(app.Menu_3);
            app.Menu_22.Text = '响应面...';
            app.Menu_13 = uimenu(app.Menu_3);
            app.Menu_13.Text = '效果分析...';
            app.Menu_23 = uimenu(app.Menu_3);
            app.Menu_23.Text = '统计数据...';
            app.Menu_24 = uimenu(app.Menu_3);
            app.Menu_24.Text = '分类...';
            app.Menu_25 = uimenu(app.Menu_3);
            app.Menu_25.Text = '预测...';
            app.MCRMenu = uimenu(app.Menu_3);
            app.MCRMenu.Text = 'MCR...';
            app.MSCEMSCMenu = uimenu(app.Menu_3);
            app.MSCEMSCMenu.Text = 'MSC/EMSC...';
            app.Menu_12 = uimenu(app.Menu_3);
            app.Menu_12.Text = '数据表格...';
            app.Menu_26 = uimenu(app.Menu_3);
            app.Menu_26.Text = '总体视图...';
            app.Menu_4 = uimenu(app.TheUnscramblerUIFigure);
            app.Menu_4.Text = '帮助';
            app.Menu_20 = uimenu(app.Menu_4);
            app.Menu_20.Text = '主题帮助                         ';
            app.Menu_16 = uimenu(app.Menu_4);
            app.Menu_16.Text = '教程练习';
            app.Menu_14 = uimenu(app.Menu_4);
            app.Menu_14.Text = '快速浏览';
            app.Menu_15 = uimenu(app.Menu_4);
            app.Menu_15.Text = '最新消息...';
            app.Menu_17 = uimenu(app.Menu_4);
            app.Menu_17.Text = '关于...';
            app.Menu_18 = uimenu(app.Menu_4);
            app.Menu_18.Text = '许可证更改...';
            app.Menu_19 = uimenu(app.Menu_4);
            app.Menu_19.Text = '许可证...';
            app.Toolbar = uitoolbar(app.TheUnscramblerUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'创建数据表格'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'wenjian.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'打开'};
            app.PushTool2.Icon = '24gl-folderOpen (1).png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'主题帮助'};
            app.PushTool3.Icon = '问号 (1).png';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'帮助'};
            app.PushTool4.Icon = '28D鼠标箭头.png';
            app.Image = uiimage(app.TheUnscramblerUIFigure);
            app.Image.ImageSource = 'the2.png';
            app.HTML = uihtml(app.TheUnscramblerUIFigure);
            app.HTML.HTMLSource = 'C:\Users\艾何欣\Desktop\need\style.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.HTML.Visible = 'off';
            % Show the figure after all components are created
            app.TheUnscramblerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TheUnscrambler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TheUnscramblerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TheUnscramblerUIFigure)
        end
    end
end