classdef TheUnscramblerDATA1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        Menu_18                        matlab.ui.container.Menu
        Menu_19                        matlab.ui.container.Menu
        Menu_16                        matlab.ui.container.Menu
        Menu_17                        matlab.ui.container.Menu
        Menu_24                        matlab.ui.container.Menu
        Menu_25                        matlab.ui.container.Menu
        Menu_10                        matlab.ui.container.Menu
        UnscramblerResultsMenu         matlab.ui.container.Menu
        UnscramblerDataMenu            matlab.ui.container.Menu
        U5DataMenu                     matlab.ui.container.Menu
        UnscramblerASCIIMenu           matlab.ui.container.Menu
        ASCIIMenu                      matlab.ui.container.Menu
        lotusMenu                      matlab.ui.container.Menu
        ExcelMenu                      matlab.ui.container.Menu
        MatlabMenu                     matlab.ui.container.Menu
        NSASMenu                       matlab.ui.container.Menu
        DatabaseMenu                   matlab.ui.container.Menu
        JCAMPDXMenu                    matlab.ui.container.Menu
        TrackerMenu                    matlab.ui.container.Menu
        APCMenu                        matlab.ui.container.Menu
        GRAMSMenu                      matlab.ui.container.Menu
        MVACDFMenu                     matlab.ui.container.Menu
        CLASSPASpectrOnMenu            matlab.ui.container.Menu
        IndicoMenu                     matlab.ui.container.Menu
        BFF3Menu                       matlab.ui.container.Menu
        OPUSMenu                       matlab.ui.container.Menu
        UserDefinedImportUDIMenu       matlab.ui.container.Menu
        DMenu                          matlab.ui.container.Menu
        UnscramblerResultsMenu_2       matlab.ui.container.Menu
        ASCIIMenu_2                    matlab.ui.container.Menu
        ExcelMenu_2                    matlab.ui.container.Menu
        JCAMPDXMenu_2                  matlab.ui.container.Menu
        MatlabMenu_2                   matlab.ui.container.Menu
        F3DMenu                        matlab.ui.container.Menu
        Menu_11                        matlab.ui.container.Menu
        Menu_23                        matlab.ui.container.Menu
        AsNonDesignMenu                matlab.ui.container.Menu
        AsModifiedDesignMenu           matlab.ui.container.Menu
        As2DDataTableMenu              matlab.ui.container.Menu
        As3DDataTableMenu              matlab.ui.container.Menu
        ConvertVectortoDataTableMenu   matlab.ui.container.Menu
        Menu_20                        matlab.ui.container.Menu
        Menu_22                        matlab.ui.container.Menu
        Menu_21                        matlab.ui.container.Menu
        Menu_12                        matlab.ui.container.Menu
        Menu_26                        matlab.ui.container.Menu
        Menu_27                        matlab.ui.container.Menu
        Menu_13                        matlab.ui.container.Menu
        Menu_14                        matlab.ui.container.Menu
        Menu_15                        matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        CutMenu                        matlab.ui.container.Menu
        CopyMenu                       matlab.ui.container.Menu
        PasteMenu                      matlab.ui.container.Menu
        FillMenu                       matlab.ui.container.Menu
        FindMissingMenu                matlab.ui.container.Menu
        FindReplaceMenu                matlab.ui.container.Menu
        InsertMenu                     matlab.ui.container.Menu
        SamplesMenu_2                  matlab.ui.container.Menu
        VariablesMenu_2                matlab.ui.container.Menu
        CateGoryVariableMenu_2         matlab.ui.container.Menu
        MixtureVariablesMenu_2         matlab.ui.container.Menu
        AppendMenu                     matlab.ui.container.Menu
        SamplesMenu                    matlab.ui.container.Menu
        VariablesMenu                  matlab.ui.container.Menu
        CateGoryVariableMenu           matlab.ui.container.Menu
        MixtureVariablesMenu           matlab.ui.container.Menu
        DeleteMenu                     matlab.ui.container.Menu
        ConverttoCategoryVariableMenu  matlab.ui.container.Menu
        SpiteCategoryVariableMenu      matlab.ui.container.Menu
        CorrectMixtureVariablesMenu    matlab.ui.container.Menu
        GotoMenu                       matlab.ui.container.Menu
        SelectSamplesMenu              matlab.ui.container.Menu
        SelectVariablesMenu            matlab.ui.container.Menu
        SelectAllMenu                  matlab.ui.container.Menu
        AdjustWidthMenu                matlab.ui.container.Menu
        Menu_3                         matlab.ui.container.Menu
        SampleStatisticsMenu           matlab.ui.container.Menu
        VariableStatisticsMenu         matlab.ui.container.Menu
        SampleNamesMenu                matlab.ui.container.Menu
        PointNamesMenu                 matlab.ui.container.Menu
        XYVariableNumberingMenu        matlab.ui.container.Menu
        LevelIndicesMenu               matlab.ui.container.Menu
        StandardSampleSequenceMenu     matlab.ui.container.Menu
        ExperimentSampleSequenceMenu   matlab.ui.container.Menu
        ToolbarsMenu                   matlab.ui.container.Menu
        StatusBarMenu                  matlab.ui.container.Menu
        Menu_4                         matlab.ui.container.Menu
        LineMenu                       matlab.ui.container.Menu
        DScatterMenu                   matlab.ui.container.Menu
        DScatterMenu_2                 matlab.ui.container.Menu
        NormalProbabilityMenu          matlab.ui.container.Menu
        HistogramMenu                  matlab.ui.container.Menu
        MatrixMenu                     matlab.ui.container.Menu
        Matrix3DMenu                   matlab.ui.container.Menu
        Menu_5                         matlab.ui.container.Menu
        ComputeGeneralMenu             matlab.ui.container.Menu
        TransformMenu                  matlab.ui.container.Menu
        SmoothingMenu                  matlab.ui.container.Menu
        MovingAverageMenu              matlab.ui.container.Menu
        SGolayMenu                     matlab.ui.container.Menu
        MedianFliterMenu               matlab.ui.container.Menu
        GaussianFliterMenu             matlab.ui.container.Menu
        NormalizeMenu                  matlab.ui.container.Menu
        SpectroscopicMenu              matlab.ui.container.Menu
        MSCEMSCMenu_2                  matlab.ui.container.Menu
        NoiseMenu                      matlab.ui.container.Menu
        DerivativesMenu                matlab.ui.container.Menu
        NorrisGapMenu                  matlab.ui.container.Menu
        GapsegmentMenu                 matlab.ui.container.Menu
        SGolayMenu_2                   matlab.ui.container.Menu
        BaselineMenu                   matlab.ui.container.Menu
        SNVMenu                        matlab.ui.container.Menu
        CenterandscaleMenu             matlab.ui.container.Menu
        ReduceAverageMenu              matlab.ui.container.Menu
        TransposeMenu                  matlab.ui.container.Menu
        UserdefinedMenu                matlab.ui.container.Menu
        SortsamplesMenu                matlab.ui.container.Menu
        SortSamplesbySetsMenu          matlab.ui.container.Menu
        SortVariablesbySetsMenu        matlab.ui.container.Menu
        ShiftVariablesMenu             matlab.ui.container.Menu
        ReverseSampleOrderMenu         matlab.ui.container.Menu
        ReverseVariableOrderMenu       matlab.ui.container.Menu
        Swap3DLayoutMenu               matlab.ui.container.Menu
        SwapSampleVariablesMenu        matlab.ui.container.Menu
        PriVariableSecVariableMenu     matlab.ui.container.Menu
        PriVariableSampleMenu          matlab.ui.container.Menu
        SecVariableSampleMenu          matlab.ui.container.Menu
        PriSampleSecSampleMenu         matlab.ui.container.Menu
        PriSampleVariableMenu          matlab.ui.container.Menu
        SecSampleVariableMenu          matlab.ui.container.Menu
        Toggle3DLayoutsMenu            matlab.ui.container.Menu
        UndoMenu                       matlab.ui.container.Menu
        RedoMenu                       matlab.ui.container.Menu
        PropertiesMenu_2               matlab.ui.container.Menu
        LayoutMenu                     matlab.ui.container.Menu
        EditSetMenu                    matlab.ui.container.Menu
        Menu_6                         matlab.ui.container.Menu
        StatisticsMenu                 matlab.ui.container.Menu
        ClusteringMenu                 matlab.ui.container.Menu
        PCAMenu_2                      matlab.ui.container.Menu
        MCRMenu_2                      matlab.ui.container.Menu
        RegressionMenu_2               matlab.ui.container.Menu
        ResponseSurfaceMenu_2          matlab.ui.container.Menu
        AnalysisofEffectsMenu_2        matlab.ui.container.Menu
        ClassifyMenu                   matlab.ui.container.Menu
        PredictMenu                    matlab.ui.container.Menu
        UserdefinedAnalysisMenu        matlab.ui.container.Menu
        Menu_7                         matlab.ui.container.Menu
        AllMenu                        matlab.ui.container.Menu
        PCAMenu                        matlab.ui.container.Menu
        RegressionMenu                 matlab.ui.container.Menu
        ResponseSurfaceMenu            matlab.ui.container.Menu
        AnalysisofEffectsMenu          matlab.ui.container.Menu
        StatisicsMenu                  matlab.ui.container.Menu
        ClassificationMenu             matlab.ui.container.Menu
        PredictionMenu                 matlab.ui.container.Menu
        MCRMenu                        matlab.ui.container.Menu
        MSCEMSCMenu                    matlab.ui.container.Menu
        DataTableMenu                  matlab.ui.container.Menu
        GeneralViewMenu                matlab.ui.container.Menu
        Menu_8                         matlab.ui.container.Menu
        CasecodeMenu                   matlab.ui.container.Menu
        TileHorizontallyMenu           matlab.ui.container.Menu
        TileVerticallyMenu             matlab.ui.container.Menu
        CloseCurrentMenu               matlab.ui.container.Menu
        CloseAllMenu                   matlab.ui.container.Menu
        DATAMenu                       matlab.ui.container.Menu
        Menu_9                         matlab.ui.container.Menu
        TheUnscramblerHelpTopicsMenu   matlab.ui.container.Menu
        TutorialExercisesMenu          matlab.ui.container.Menu
        QuickTourMenu                  matlab.ui.container.Menu
        TipoftheDayMenu                matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        ChangeLicenseMenu              matlab.ui.container.Menu
        LicenseeMenu                   matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        PushTool3                      matlab.ui.container.toolbar.PushTool
        PushTool4                      matlab.ui.container.toolbar.PushTool
        PushTool5                      matlab.ui.container.toolbar.PushTool
        PushTool6                      matlab.ui.container.toolbar.PushTool
        PushTool7                      matlab.ui.container.toolbar.PushTool
        PushTool8                      matlab.ui.container.toolbar.PushTool
        PushTool9                      matlab.ui.container.toolbar.PushTool
        PushTool10                     matlab.ui.container.toolbar.PushTool
        PushTool11                     matlab.ui.container.toolbar.PushTool
        PushTool12                     matlab.ui.container.toolbar.PushTool
        Toolbar2                       matlab.ui.container.Toolbar
        PushTool13                     matlab.ui.container.toolbar.PushTool
        PushTool14                     matlab.ui.container.toolbar.PushTool
        PushTool15                     matlab.ui.container.toolbar.PushTool
        PushTool16                     matlab.ui.container.toolbar.PushTool
        PushTool17                     matlab.ui.container.toolbar.PushTool
        PushTool18                     matlab.ui.container.toolbar.PushTool
        PushTool19                     matlab.ui.container.toolbar.PushTool
        PushTool20                     matlab.ui.container.toolbar.PushTool
        PushTool21                     matlab.ui.container.toolbar.PushTool
        PushTool22                     matlab.ui.container.toolbar.PushTool
        Toolbar3                       matlab.ui.container.Toolbar
        PushTool23                     matlab.ui.container.toolbar.PushTool
        PushTool24                     matlab.ui.container.toolbar.PushTool
        PushTool25                     matlab.ui.container.toolbar.PushTool
        PushTool26                     matlab.ui.container.toolbar.PushTool
        PushTool27                     matlab.ui.container.toolbar.PushTool
        PushTool28                     matlab.ui.container.toolbar.PushTool
        UITable                        matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, chooseFile)
            global chooseFile
            text = readtable(chooseFile);
            app.UITable.Data = text;
            % 设置表格标题
            app.UITable.ColumnName = text.Properties.VariableNames;
        end

        % Menu selected function: Menu_17
        function Menu_17Selected(app, event)
            run TheUnscrambler.mlapp;
            delete(app.UIFigure);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
%             numIndices 获取到的是当前选中的这一列下的单元格的数量
%             numIndices = size(indices, 1);
%             cells = cell(numIndices, 1);
%             for i = 1:numIndices        
%                 %cells{i} = mat2str(indices(i, :));
%             end
%             sprintf函数将所有选中单元格的索引拼接为一个字符串
%             msg = sprintf('%s\n', cells{:});
%             msgbox(msg);
            
%             selectedCols = unique(indices(:,2));
%             numCols = numel(selectedCols);
%             这里是获取当前选中了几列
%             msg = sprintf('当前选中的单元格的列数：%d', numCols);
%             msgbox(msg);

            selectedCol = indices(1, 2);
            % 假设只选择了一个单元格    
%             msg = sprintf('当前选中单元格所在的列是第%d列', selectedCol);
            % 将获取到的列数转换为数值型
            global numCol
            numCol = str2double(num2str(selectedCol));
%             msgbox(msg);
            
%             selectedCols = indices(:, 2);
%             sortedCols = sort(selectedCols);
%             firstCol = sortedCols(1);
%             lastCol = sortedCols(end);
%             msg = sprintf('第一个列选中的单元格所在的列：%d\n最后一列选中的单元格所在的列：%d', firstCol, lastCol);
%             msgbox(msg);
        end

        % Clicked callback: PushTool23
        function PushTool23Clicked(app, event)
            global selectedBtn
            selectedBtn = "line";
            run TheUnscramblerDATA2.mlapp;
        end

        % Clicked callback: PushTool24
        function PushTool24Clicked(app, event)
            global selectedBtn
            selectedBtn = "2D_scatter";
            run TheUnscramblerDATA2.mlapp;
        end

        % Clicked callback: PushTool25
        function PushTool25Clicked(app, event)
            global selectedBtn
            selectedBtn = "3D_scatter";
            run TheUnscramblerDATA2.mlapp;
        end

        % Clicked callback: PushTool26
        function PushTool26Clicked(app, event)
            global selectedBtn
            selectedBtn = "normal_probablity";
            run TheUnscramblerDATA2.mlapp;
        end

        % Clicked callback: PushTool27
        function PushTool27Clicked(app, event)
            global selectedBtn
            selectedBtn = "histogram";
            run TheUnscramblerDATA2.mlapp;
        end

        % Clicked callback: PushTool28
        function PushTool28Clicked(app, event)
            global selectedBtn
            selectedBtn = "matrix";
            run TheUnscramblerDATA2.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_18 = uimenu(app.Menu);
            app.Menu_18.Text = '新建 ...';
            app.Menu_19 = uimenu(app.Menu);
            app.Menu_19.Text = '新建设计 ...';
            app.Menu_16 = uimenu(app.Menu);
            app.Menu_16.Text = '打开 ...';
            app.Menu_17 = uimenu(app.Menu);
            app.Menu_17.MenuSelectedFcn = createCallbackFcn(app, @Menu_17Selected, true);
            app.Menu_17.Text = '关闭';
            app.Menu_24 = uimenu(app.Menu);
            app.Menu_24.Text = '保存';
            app.Menu_25 = uimenu(app.Menu);
            app.Menu_25.Text = '另存为...';
            app.Menu_10 = uimenu(app.Menu);
            app.Menu_10.Text = '导入';
            app.UnscramblerResultsMenu = uimenu(app.Menu_10);
            app.UnscramblerResultsMenu.Text = 'Unscrambler Results ...';
            app.UnscramblerDataMenu = uimenu(app.Menu_10);
            app.UnscramblerDataMenu.Text = 'Unscrambler Data ...';
            app.U5DataMenu = uimenu(app.Menu_10);
            app.U5DataMenu.Text = 'U5 Data ...';
            app.UnscramblerASCIIMenu = uimenu(app.Menu_10);
            app.UnscramblerASCIIMenu.Text = 'Unscrambler ASCII ...';
            app.ASCIIMenu = uimenu(app.Menu_10);
            app.ASCIIMenu.Text = 'ASCII ...';
            app.lotusMenu = uimenu(app.Menu_10);
            app.lotusMenu.Text = 'lotus ...';
            app.ExcelMenu = uimenu(app.Menu_10);
            app.ExcelMenu.Text = 'Excel ...';
            app.MatlabMenu = uimenu(app.Menu_10);
            app.MatlabMenu.Text = 'Matlab ...';
            app.NSASMenu = uimenu(app.Menu_10);
            app.NSASMenu.Text = 'NSAS ...';
            app.DatabaseMenu = uimenu(app.Menu_10);
            app.DatabaseMenu.Text = 'Database ...';
            app.JCAMPDXMenu = uimenu(app.Menu_10);
            app.JCAMPDXMenu.Text = 'JCAMP - DX ...';
            app.TrackerMenu = uimenu(app.Menu_10);
            app.TrackerMenu.Text = 'Tracker ...';
            app.APCMenu = uimenu(app.Menu_10);
            app.APCMenu.Text = 'APC ...';
            app.GRAMSMenu = uimenu(app.Menu_10);
            app.GRAMSMenu.Text = 'GRAMS ...';
            app.MVACDFMenu = uimenu(app.Menu_10);
            app.MVACDFMenu.Text = 'MVACDF ...';
            app.CLASSPASpectrOnMenu = uimenu(app.Menu_10);
            app.CLASSPASpectrOnMenu.Text = 'CLASS - PA & SpectrOn ...';
            app.IndicoMenu = uimenu(app.Menu_10);
            app.IndicoMenu.Text = 'Indico ...';
            app.BFF3Menu = uimenu(app.Menu_10);
            app.BFF3Menu.Text = 'BFF3 ...';
            app.OPUSMenu = uimenu(app.Menu_10);
            app.OPUSMenu.Text = 'OPUS ...';
            app.UserDefinedImportUDIMenu = uimenu(app.Menu_10);
            app.UserDefinedImportUDIMenu.Text = 'User Defined Import (UDI) ...';
            app.DMenu = uimenu(app.Menu);
            app.DMenu.Text = '导入 3-D';
            app.UnscramblerResultsMenu_2 = uimenu(app.DMenu);
            app.UnscramblerResultsMenu_2.Text = 'Unscrambler Results ...';
            app.ASCIIMenu_2 = uimenu(app.DMenu);
            app.ASCIIMenu_2.Text = 'ASCII ...';
            app.ExcelMenu_2 = uimenu(app.DMenu);
            app.ExcelMenu_2.Text = 'Excel ...';
            app.JCAMPDXMenu_2 = uimenu(app.DMenu);
            app.JCAMPDXMenu_2.Text = 'JCAMP - DX ...';
            app.MatlabMenu_2 = uimenu(app.DMenu);
            app.MatlabMenu_2.Text = 'Matlab ...';
            app.F3DMenu = uimenu(app.DMenu);
            app.F3DMenu.Text = 'F3D ...';
            app.Menu_11 = uimenu(app.Menu);
            app.Menu_11.Text = '导出 ...';
            app.Menu_23 = uimenu(app.Menu);
            app.Menu_23.Text = '复印';
            app.AsNonDesignMenu = uimenu(app.Menu_23);
            app.AsNonDesignMenu.Text = 'As Non-Design';
            app.AsModifiedDesignMenu = uimenu(app.Menu_23);
            app.AsModifiedDesignMenu.Text = 'As Modified Design ...';
            app.As2DDataTableMenu = uimenu(app.Menu_23);
            app.As2DDataTableMenu.Text = 'As 2-D Data Table';
            app.As3DDataTableMenu = uimenu(app.Menu_23);
            app.As3DDataTableMenu.Text = 'As 3-D Data Table';
            app.ConvertVectortoDataTableMenu = uimenu(app.Menu);
            app.ConvertVectortoDataTableMenu.Text = 'Convert Vector to Data Table                        ';
            app.Menu_20 = uimenu(app.Menu);
            app.Menu_20.Text = '打印';
            app.Menu_22 = uimenu(app.Menu);
            app.Menu_22.Text = '打印预览';
            app.Menu_21 = uimenu(app.Menu);
            app.Menu_21.Text = '打印实验报告 ...';
            app.Menu_12 = uimenu(app.Menu);
            app.Menu_12.Text = '打印设置 ...';
            app.Menu_26 = uimenu(app.Menu);
            app.Menu_26.Text = '锁定';
            app.Menu_27 = uimenu(app.Menu);
            app.Menu_27.Text = '特性';
            app.Menu_13 = uimenu(app.Menu);
            app.Menu_13.Text = '系统 ...';
            app.Menu_14 = uimenu(app.Menu);
            app.Menu_14.Text = '最近文件';
            app.Menu_15 = uimenu(app.Menu);
            app.Menu_15.Text = '退出';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '编辑';
            app.CutMenu = uimenu(app.Menu_2);
            app.CutMenu.Text = 'Cut';
            app.CopyMenu = uimenu(app.Menu_2);
            app.CopyMenu.Text = 'Copy';
            app.PasteMenu = uimenu(app.Menu_2);
            app.PasteMenu.Text = 'Paste';
            app.FillMenu = uimenu(app.Menu_2);
            app.FillMenu.Text = 'Fill';
            app.FindMissingMenu = uimenu(app.Menu_2);
            app.FindMissingMenu.Text = 'Find Missing ...';
            app.FindReplaceMenu = uimenu(app.Menu_2);
            app.FindReplaceMenu.Text = 'Find/Replace ...';
            app.InsertMenu = uimenu(app.Menu_2);
            app.InsertMenu.Text = 'Insert';
            app.SamplesMenu_2 = uimenu(app.InsertMenu);
            app.SamplesMenu_2.Text = 'Samples ...';
            app.VariablesMenu_2 = uimenu(app.InsertMenu);
            app.VariablesMenu_2.Text = 'Variables ...';
            app.CateGoryVariableMenu_2 = uimenu(app.InsertMenu);
            app.CateGoryVariableMenu_2.Text = 'CateGory Variable ...       ';
            app.MixtureVariablesMenu_2 = uimenu(app.InsertMenu);
            app.MixtureVariablesMenu_2.Text = 'Mixture Variables ...';
            app.AppendMenu = uimenu(app.Menu_2);
            app.AppendMenu.Text = 'Append';
            app.SamplesMenu = uimenu(app.AppendMenu);
            app.SamplesMenu.Text = 'Samples ...';
            app.VariablesMenu = uimenu(app.AppendMenu);
            app.VariablesMenu.Text = 'Variables ...';
            app.CateGoryVariableMenu = uimenu(app.AppendMenu);
            app.CateGoryVariableMenu.Text = 'CateGory Variable ...       ';
            app.MixtureVariablesMenu = uimenu(app.AppendMenu);
            app.MixtureVariablesMenu.Text = 'Mixture Variables ...';
            app.DeleteMenu = uimenu(app.Menu_2);
            app.DeleteMenu.Text = 'Delete';
            app.ConverttoCategoryVariableMenu = uimenu(app.Menu_2);
            app.ConverttoCategoryVariableMenu.Text = 'Convert to Category Variable ...         ';
            app.SpiteCategoryVariableMenu = uimenu(app.Menu_2);
            app.SpiteCategoryVariableMenu.Text = 'Spite Category Variable';
            app.CorrectMixtureVariablesMenu = uimenu(app.Menu_2);
            app.CorrectMixtureVariablesMenu.Text = 'Correct Mixture Variables ...';
            app.GotoMenu = uimenu(app.Menu_2);
            app.GotoMenu.Text = 'Go to ...';
            app.SelectSamplesMenu = uimenu(app.Menu_2);
            app.SelectSamplesMenu.Text = 'Select Samples ...';
            app.SelectVariablesMenu = uimenu(app.Menu_2);
            app.SelectVariablesMenu.Text = 'Select Variables ...';
            app.SelectAllMenu = uimenu(app.Menu_2);
            app.SelectAllMenu.Text = 'Select All';
            app.AdjustWidthMenu = uimenu(app.Menu_2);
            app.AdjustWidthMenu.Text = 'Adjust Width';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = '视图';
            app.SampleStatisticsMenu = uimenu(app.Menu_3);
            app.SampleStatisticsMenu.Text = 'Sample Statistics      ';
            app.VariableStatisticsMenu = uimenu(app.Menu_3);
            app.VariableStatisticsMenu.Text = 'Variable Statistics';
            app.SampleNamesMenu = uimenu(app.Menu_3);
            app.SampleNamesMenu.Text = 'Sample Names';
            app.PointNamesMenu = uimenu(app.Menu_3);
            app.PointNamesMenu.Text = 'Point Names';
            app.XYVariableNumberingMenu = uimenu(app.Menu_3);
            app.XYVariableNumberingMenu.Text = 'X/Y Variable Numbering';
            app.LevelIndicesMenu = uimenu(app.Menu_3);
            app.LevelIndicesMenu.Text = 'Level Indices';
            app.StandardSampleSequenceMenu = uimenu(app.Menu_3);
            app.StandardSampleSequenceMenu.Text = 'Standard Sample Sequence';
            app.ExperimentSampleSequenceMenu = uimenu(app.Menu_3);
            app.ExperimentSampleSequenceMenu.Text = 'Experiment Sample Sequence            ';
            app.ToolbarsMenu = uimenu(app.Menu_3);
            app.ToolbarsMenu.Text = 'Toolbars ...';
            app.StatusBarMenu = uimenu(app.Menu_3);
            app.StatusBarMenu.Text = 'Status Bar';
            app.Menu_4 = uimenu(app.UIFigure);
            app.Menu_4.Text = '绘图';
            app.LineMenu = uimenu(app.Menu_4);
            app.LineMenu.Text = 'Line';
            app.DScatterMenu = uimenu(app.Menu_4);
            app.DScatterMenu.Text = '2D Scatter';
            app.DScatterMenu_2 = uimenu(app.Menu_4);
            app.DScatterMenu_2.Text = '3D Scatter';
            app.NormalProbabilityMenu = uimenu(app.Menu_4);
            app.NormalProbabilityMenu.Text = 'Normal Probability       ';
            app.HistogramMenu = uimenu(app.Menu_4);
            app.HistogramMenu.Text = 'Histogram';
            app.MatrixMenu = uimenu(app.Menu_4);
            app.MatrixMenu.Text = 'Matrix';
            app.Matrix3DMenu = uimenu(app.Menu_4);
            app.Matrix3DMenu.Text = 'Matrix 3-D';
            app.Menu_5 = uimenu(app.UIFigure);
            app.Menu_5.Text = '修整';
            app.ComputeGeneralMenu = uimenu(app.Menu_5);
            app.ComputeGeneralMenu.Text = 'Compute General ...';
            app.TransformMenu = uimenu(app.Menu_5);
            app.TransformMenu.Text = 'Transform';
            app.SmoothingMenu = uimenu(app.TransformMenu);
            app.SmoothingMenu.Text = 'Smoothing';
            app.MovingAverageMenu = uimenu(app.SmoothingMenu);
            app.MovingAverageMenu.Text = 'Moving Average ...      ';
            app.SGolayMenu = uimenu(app.SmoothingMenu);
            app.SGolayMenu.Text = 'S, Golay ...';
            app.MedianFliterMenu = uimenu(app.SmoothingMenu);
            app.MedianFliterMenu.Text = 'Median Fliter ...';
            app.GaussianFliterMenu = uimenu(app.SmoothingMenu);
            app.GaussianFliterMenu.Text = 'Gaussian Fliter ...';
            app.NormalizeMenu = uimenu(app.TransformMenu);
            app.NormalizeMenu.Text = 'Normalize ...';
            app.SpectroscopicMenu = uimenu(app.TransformMenu);
            app.SpectroscopicMenu.Text = 'Spectroscopic ...';
            app.MSCEMSCMenu_2 = uimenu(app.TransformMenu);
            app.MSCEMSCMenu_2.Text = 'MSC/EMSC ...';
            app.NoiseMenu = uimenu(app.TransformMenu);
            app.NoiseMenu.Text = 'Noise ...';
            app.DerivativesMenu = uimenu(app.TransformMenu);
            app.DerivativesMenu.Text = 'Derivatives';
            app.NorrisGapMenu = uimenu(app.DerivativesMenu);
            app.NorrisGapMenu.Text = 'Norris Gap ...       ';
            app.GapsegmentMenu = uimenu(app.DerivativesMenu);
            app.GapsegmentMenu.Text = 'Gap-segment ...     ';
            app.SGolayMenu_2 = uimenu(app.DerivativesMenu);
            app.SGolayMenu_2.Text = 'S, Golay ...';
            app.BaselineMenu = uimenu(app.TransformMenu);
            app.BaselineMenu.Text = 'Baseline ...';
            app.SNVMenu = uimenu(app.TransformMenu);
            app.SNVMenu.Text = 'SNV ...';
            app.CenterandscaleMenu = uimenu(app.TransformMenu);
            app.CenterandscaleMenu.Text = 'Center and scale ...';
            app.ReduceAverageMenu = uimenu(app.TransformMenu);
            app.ReduceAverageMenu.Text = 'Reduce (Average) ...         ';
            app.TransposeMenu = uimenu(app.TransformMenu);
            app.TransposeMenu.Text = 'Transpose';
            app.UserdefinedMenu = uimenu(app.TransformMenu);
            app.UserdefinedMenu.Text = 'User-defined ...';
            app.SortsamplesMenu = uimenu(app.Menu_5);
            app.SortsamplesMenu.Text = 'Sort samples ...';
            app.SortSamplesbySetsMenu = uimenu(app.Menu_5);
            app.SortSamplesbySetsMenu.Text = 'Sort Samples by Sets                       ';
            app.SortVariablesbySetsMenu = uimenu(app.Menu_5);
            app.SortVariablesbySetsMenu.Text = 'Sort Variables by Sets';
            app.ShiftVariablesMenu = uimenu(app.Menu_5);
            app.ShiftVariablesMenu.Text = 'Shift Variables ...';
            app.ReverseSampleOrderMenu = uimenu(app.Menu_5);
            app.ReverseSampleOrderMenu.Text = 'Reverse Sample Order';
            app.ReverseVariableOrderMenu = uimenu(app.Menu_5);
            app.ReverseVariableOrderMenu.Text = 'Reverse Variable Order';
            app.Swap3DLayoutMenu = uimenu(app.Menu_5);
            app.Swap3DLayoutMenu.Text = 'Swap 3-D Layout';
            app.SwapSampleVariablesMenu = uimenu(app.Menu_5);
            app.SwapSampleVariablesMenu.Text = 'Swap Sample & Variables';
            app.PriVariableSecVariableMenu = uimenu(app.SwapSampleVariablesMenu);
            app.PriVariableSecVariableMenu.Text = 'Pri Variable & Sec Variable     ';
            app.PriVariableSampleMenu = uimenu(app.SwapSampleVariablesMenu);
            app.PriVariableSampleMenu.Text = 'Pri Variable & Sample';
            app.SecVariableSampleMenu = uimenu(app.SwapSampleVariablesMenu);
            app.SecVariableSampleMenu.Text = 'Sec Variable & Sample';
            app.PriSampleSecSampleMenu = uimenu(app.SwapSampleVariablesMenu);
            app.PriSampleSecSampleMenu.Text = 'Pri Sample & Sec Sample';
            app.PriSampleVariableMenu = uimenu(app.SwapSampleVariablesMenu);
            app.PriSampleVariableMenu.Text = 'Pri Sample & Variable';
            app.SecSampleVariableMenu = uimenu(app.SwapSampleVariablesMenu);
            app.SecSampleVariableMenu.Text = 'Sec Sample & Variable';
            app.Toggle3DLayoutsMenu = uimenu(app.Menu_5);
            app.Toggle3DLayoutsMenu.Text = 'Toggle 3-D Layouts';
            app.UndoMenu = uimenu(app.Menu_5);
            app.UndoMenu.Text = 'Undo';
            app.RedoMenu = uimenu(app.Menu_5);
            app.RedoMenu.Text = 'Redo';
            app.PropertiesMenu_2 = uimenu(app.Menu_5);
            app.PropertiesMenu_2.Text = 'Properties';
            app.LayoutMenu = uimenu(app.Menu_5);
            app.LayoutMenu.Text = 'Layout ...';
            app.EditSetMenu = uimenu(app.Menu_5);
            app.EditSetMenu.Text = 'Edit Set ...';
            app.Menu_6 = uimenu(app.UIFigure);
            app.Menu_6.Text = '任务';
            app.StatisticsMenu = uimenu(app.Menu_6);
            app.StatisticsMenu.Text = 'Statistics ...';
            app.ClusteringMenu = uimenu(app.Menu_6);
            app.ClusteringMenu.Text = 'Clustering ...';
            app.PCAMenu_2 = uimenu(app.Menu_6);
            app.PCAMenu_2.Text = 'PCA ...';
            app.MCRMenu_2 = uimenu(app.Menu_6);
            app.MCRMenu_2.Text = 'MCR ...';
            app.RegressionMenu_2 = uimenu(app.Menu_6);
            app.RegressionMenu_2.Text = 'Regression ...';
            app.ResponseSurfaceMenu_2 = uimenu(app.Menu_6);
            app.ResponseSurfaceMenu_2.Text = 'Response Surface ...            ';
            app.AnalysisofEffectsMenu_2 = uimenu(app.Menu_6);
            app.AnalysisofEffectsMenu_2.Text = 'Analysis of Effects ...';
            app.ClassifyMenu = uimenu(app.Menu_6);
            app.ClassifyMenu.Text = 'Classify ...';
            app.PredictMenu = uimenu(app.Menu_6);
            app.PredictMenu.Text = 'Predict ...';
            app.UserdefinedAnalysisMenu = uimenu(app.Menu_6);
            app.UserdefinedAnalysisMenu.Text = 'User-defined Analysis ...';
            app.Menu_7 = uimenu(app.UIFigure);
            app.Menu_7.Text = '结果';
            app.AllMenu = uimenu(app.Menu_7);
            app.AllMenu.Text = 'All ...';
            app.PCAMenu = uimenu(app.Menu_7);
            app.PCAMenu.Text = 'PCA ...';
            app.RegressionMenu = uimenu(app.Menu_7);
            app.RegressionMenu.Text = 'Regression ...';
            app.ResponseSurfaceMenu = uimenu(app.Menu_7);
            app.ResponseSurfaceMenu.Text = 'Response Surface ...                       ';
            app.AnalysisofEffectsMenu = uimenu(app.Menu_7);
            app.AnalysisofEffectsMenu.Text = 'Analysis of Effects ...';
            app.StatisicsMenu = uimenu(app.Menu_7);
            app.StatisicsMenu.Text = 'Statisics ...';
            app.ClassificationMenu = uimenu(app.Menu_7);
            app.ClassificationMenu.Text = 'Classification ...';
            app.PredictionMenu = uimenu(app.Menu_7);
            app.PredictionMenu.Text = 'Prediction ...';
            app.MCRMenu = uimenu(app.Menu_7);
            app.MCRMenu.Text = 'MCR ...';
            app.MSCEMSCMenu = uimenu(app.Menu_7);
            app.MSCEMSCMenu.Text = 'MSC/EMSC ...';
            app.DataTableMenu = uimenu(app.Menu_7);
            app.DataTableMenu.Text = 'Data Table ...';
            app.GeneralViewMenu = uimenu(app.Menu_7);
            app.GeneralViewMenu.Text = 'General View ...';
            app.Menu_8 = uimenu(app.UIFigure);
            app.Menu_8.Text = '窗口';
            app.CasecodeMenu = uimenu(app.Menu_8);
            app.CasecodeMenu.Text = 'Casecode';
            app.TileHorizontallyMenu = uimenu(app.Menu_8);
            app.TileHorizontallyMenu.Text = 'Tile Horizontally';
            app.TileVerticallyMenu = uimenu(app.Menu_8);
            app.TileVerticallyMenu.Text = 'Tile Vertically';
            app.CloseCurrentMenu = uimenu(app.Menu_8);
            app.CloseCurrentMenu.Text = 'Close Current                                      ';
            app.CloseAllMenu = uimenu(app.Menu_8);
            app.CloseAllMenu.Text = 'Close All';
            app.DATAMenu = uimenu(app.Menu_8);
            app.DATAMenu.Text = '✔     1 DATA';
            app.Menu_9 = uimenu(app.UIFigure);
            app.Menu_9.Text = '帮助';
            app.TheUnscramblerHelpTopicsMenu = uimenu(app.Menu_9);
            app.TheUnscramblerHelpTopicsMenu.Text = 'The Unscrambler Help Topics            ';
            app.TutorialExercisesMenu = uimenu(app.Menu_9);
            app.TutorialExercisesMenu.Text = 'Tutorial Exercises';
            app.QuickTourMenu = uimenu(app.Menu_9);
            app.QuickTourMenu.Text = 'Quick Tour';
            app.TipoftheDayMenu = uimenu(app.Menu_9);
            app.TipoftheDayMenu.Text = 'Tip of the Day ...';
            app.AboutMenu = uimenu(app.Menu_9);
            app.AboutMenu.Text = 'About ...';
            app.ChangeLicenseMenu = uimenu(app.Menu_9);
            app.ChangeLicenseMenu.Text = 'Change License ...';
            app.LicenseeMenu = uimenu(app.Menu_9);
            app.LicenseeMenu.Text = 'Licensee ...';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = 'wenjian.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Icon = '24gl-folderOpen (1).png';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Icon = '保存.png';
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.Icon = 'cut.png';
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.Icon = '复制.png';
            app.PushTool7 = uipushtool(app.Toolbar);
            app.PushTool7.Icon = 'paste.png';
            app.PushTool8 = uipushtool(app.Toolbar);
            app.PushTool8.Icon = 'print.png';
            app.PushTool9 = uipushtool(app.Toolbar);
            app.PushTool9.Icon = '文件_搜索.png';
            app.PushTool10 = uipushtool(app.Toolbar);
            app.PushTool10.Icon = 'Notes.png';
            app.PushTool11 = uipushtool(app.Toolbar);
            app.PushTool11.Icon = '问号 (1).png';
            app.PushTool12 = uipushtool(app.Toolbar);
            app.PushTool12.Icon = '28D鼠标箭头.png';
            app.Toolbar2 = uitoolbar(app.UIFigure);
            app.PushTool13 = uipushtool(app.Toolbar2);
            app.PushTool13.Icon = 'insert row above.png';
            app.PushTool14 = uipushtool(app.Toolbar2);
            app.PushTool14.Icon = 'insert row left.png';
            app.PushTool15 = uipushtool(app.Toolbar2);
            app.PushTool15.Icon = '删除.png';
            app.PushTool16 = uipushtool(app.Toolbar2);
            app.PushTool16.Icon = '开始.png';
            app.PushTool17 = uipushtool(app.Toolbar2);
            app.PushTool17.Icon = '前往.png';
            app.PushTool18 = uipushtool(app.Toolbar2);
            app.PushTool18.Icon = 'icon-design-38.png';
            app.PushTool19 = uipushtool(app.Toolbar2);
            app.PushTool19.Icon = 'calculation.png';
            app.PushTool20 = uipushtool(app.Toolbar2);
            app.PushTool20.Icon = 'export.png';
            app.PushTool21 = uipushtool(app.Toolbar2);
            app.PushTool21.Icon = 'undo.png';
            app.PushTool22 = uipushtool(app.Toolbar2);
            app.PushTool22.Icon = 'redo.png';
            app.Toolbar3 = uitoolbar(app.UIFigure);
            app.PushTool23 = uipushtool(app.Toolbar3);
            app.PushTool23.ClickedCallback = createCallbackFcn(app, @PushTool23Clicked, true);
            app.PushTool23.Icon = '图表-折线图.png';
            app.PushTool24 = uipushtool(app.Toolbar3);
            app.PushTool24.ClickedCallback = createCallbackFcn(app, @PushTool24Clicked, true);
            app.PushTool24.Icon = '散点图_复制.png';
            app.PushTool25 = uipushtool(app.Toolbar3);
            app.PushTool25.ClickedCallback = createCallbackFcn(app, @PushTool25Clicked, true);
            app.PushTool25.Icon = '3D图表.png';
            app.PushTool26 = uipushtool(app.Toolbar3);
            app.PushTool26.ClickedCallback = createCallbackFcn(app, @PushTool26Clicked, true);
            app.PushTool26.Icon = '曲线-图形对象_jurassic.png';
            app.PushTool27 = uipushtool(app.Toolbar3);
            app.PushTool27.ClickedCallback = createCallbackFcn(app, @PushTool27Clicked, true);
            app.PushTool27.Icon = 'histogram.png';
            app.PushTool28 = uipushtool(app.Toolbar3);
            app.PushTool28.ClickedCallback = createCallbackFcn(app, @PushTool28Clicked, true);
            app.PushTool28.Icon = 'matrix.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Culunm 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TheUnscramblerDATA1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end